classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TextArea                        matlab.ui.control.TextArea
        OperationsPanel                 matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        TestFunctionsDropDown_3         matlab.ui.control.DropDown
        TestFunctionsDropDown_2Label_2  matlab.ui.control.Label
        Spinner3                        matlab.ui.control.Spinner
        Spinner3Label                   matlab.ui.control.Label
        Spinner2                        matlab.ui.control.Spinner
        Spinner2Label                   matlab.ui.control.Label
        WeightFactorSpinner             matlab.ui.control.Spinner
        WeightFactorSpinnerLabel        matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        SavetextoutputButton            matlab.ui.control.Button
        TestFunctionsDropDown_2         matlab.ui.control.DropDown
        TestFunctionsDropDown_2Label    matlab.ui.control.Label
        OptionsPanel                    matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        TurnsSpinner                    matlab.ui.control.Spinner
        TurnsSpinnerLabel               matlab.ui.control.Label
        upper_bound                     matlab.ui.control.Label
        UpperboundLabel_2               matlab.ui.control.Label
        AlgorithmsDropDown              matlab.ui.control.DropDown
        AlgorithmsDropDownLabel         matlab.ui.control.Label
        dimention                       matlab.ui.control.Label
        DimentionsLabel                 matlab.ui.control.Label
        lower_bound                     matlab.ui.control.Label
        LowerboundLabel                 matlab.ui.control.Label
        NumberofIterationsSpinner       matlab.ui.control.Spinner
        NumberofItterationsLabel        matlab.ui.control.Label
        NumberofAgentsSpinner           matlab.ui.control.Spinner
        NumberofAgentsLabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        lb;
        ub;
        dim;
        fobj;
        output;
        targetHistory;
    end
    
    methods (Access = public)

    function hideFields(app)
            app.Spinner2.Visible ="off";
            app.Spinner2Label.Visible ="off";

            app.Spinner3.Visible ="off";
            app.Spinner3Label.Visible ="off";
            
            app.WeightFactorSpinner.Visible ="off";
            app.WeightFactorSpinnerLabel.Visible ="off";

            app.TestFunctionsDropDown_3.Visible ="off";
            app.TestFunctionsDropDown_2Label_2.Visible="off";
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("CFDO\")
            addpath("FOX\")
            addpath("DCSO\")
            addpath("FDO\")
            addpath("MOANA\")
            addpath("CDDO\")
            addpath("DDO\")
            app.hideFields();


            [app.lb,app.ub,app.dim,app.fobj]=get_test_functions('F1');
            app.lower_bound.Text = string(app.lb);
            app.upper_bound.Text = string(app.ub);
            app.dimention.Text = string(app.dim);
        end

        % Value changed function: AlgorithmsDropDown
        function AlgorithmsDropDownValueChanged(app, event)
            app.hideFields();
            if app.AlgorithmsDropDown.Value == "CFDO"
                app.WeightFactorSpinner.Visible ="on";
                app.WeightFactorSpinnerLabel.Visible ="on";
            end
            if app.AlgorithmsDropDown.Value == "FDO"
                app.WeightFactorSpinner.Visible ="on";
                app.WeightFactorSpinnerLabel.Visible ="on";
            end
            if app.AlgorithmsDropDown.Value == "MOANA"
                app.TestFunctionsDropDown_2Label_2.Visible ="on";
                app.TestFunctionsDropDown_3.Visible ="on";
                app.TestFunctionsDropDown_2Label.Visible ="off";
                app.TestFunctionsDropDown_2.Visible ="off";

              
                [app.lb,app.ub,app.dim,app.fobj]=get_test_functions("ZDT1");
                app.lb
                app.ub
                app.lower_bound.Text = string(app.lb);
                app.upper_bound.Text = string(app.ub);
                app.dimention.Text = string(app.dim);

            else
                app.TestFunctionsDropDown_2Label.Visible ="on";
                app.TestFunctionsDropDown_2.Visible ="on";
                
            end
            

        end

        % Callback function
        function RunButtonPushed(app, event)
         
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Enable = 'off';
            app.Button.Icon = 'loading.png';
            resultInString = "";
            app.TextArea.Value = 'running algorithm ...';
            app.targetHistory = zeros(1, app.TurnsSpinner.Value);

            drawnow;

            tocs = zeros(1, app.TurnsSpinner.Value);

            for turn = 1:app.TurnsSpinner.Value
                resultInString = resultInString + sprintf('[turn %d]\n',turn);
                app.TextArea.Value = resultInString;

                tic;
                if app.AlgorithmsDropDown.Value == "DCSO"
                    [Best_score,Best_pos,Xpl,Xpt] = DCSO(app.NumberofAgentsSpinner.Value, app.NumberofIterationsSpinner.Value, app.lb,app.ub,app.dim,app.fobj);
                    app.targetHistory(1, turn) = Best_score;

                    resultInString = resultInString + "Best score: " + newline;
                    resultInString = resultInString + sprintf('%d\n', Best_score);
                    resultInString = resultInString + "Best position: " + newline;
                    resultInString = resultInString + sprintf('%d\n', Best_pos);
                    
                    tocs(turn) = toc;

                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                    
                    app.TextArea.Value = resultInString;

                elseif app.AlgorithmsDropDown.Value == "FOX"
                    [Best_score,Best_pos] = FOX(app.NumberofAgentsSpinner.Value, app.NumberofIterationsSpinner.Value, app.lb,app.ub,app.dim,app.fobj);
                    
                    resultInString = resultInString + "Best score: " + newline;
                    resultInString = resultInString + sprintf('%d\n', Best_score);
                    resultInString = resultInString + "Best position: " + newline;
                    resultInString = resultInString + sprintf('%d\n', Best_pos);
                    
                    tocs(turn) = toc;

                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                    app.TextArea.Value = resultInString;
    
                elseif app.AlgorithmsDropDown.Value == "CFDO"
                    [Best_score,Best_pos] = CFDO(app.fobj, app.NumberofIterationsSpinner.Value,app.ub, app.lb, app.dim, app.NumberofAgentsSpinner.Value, app.WeightFactorSpinner.Value);
                    
                    resultInString = resultInString + "Best score: " + newline;
                    resultInString = resultInString + sprintf('%d \n', Best_score);

                    resultInString = resultInString + "Best position (xs): ";
                    resultInString = resultInString + sprintf('%d ', Best_pos.xs) + newline;

                    resultInString = resultInString + "Best position (pace): ";
                    resultInString = resultInString + sprintf('%d, ', Best_pos.pace) + newline;
                    
                    tocs(turn) = toc;

                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                    app.TextArea.Value = resultInString;

                elseif app.AlgorithmsDropDown.Value == "FDO"
                    [Best_score,Best_pos] = FDO(app.fobj, app.NumberofIterationsSpinner.Value,app.ub, app.lb, app.dim, app.NumberofAgentsSpinner.Value, app.WeightFactorSpinner.Value);
                    app.targetHistory(1, turn) = Best_score;
                    
                    resultInString = resultInString + "Best score: " + newline;
                    resultInString = resultInString + sprintf('%d \n', Best_score);

                    resultInString = resultInString + "Best position (xs): ";
                    resultInString = resultInString + sprintf('%d ', Best_pos.xs) + newline;

                    resultInString = resultInString + "Best position (pace): ";
                    resultInString = resultInString + sprintf('%d, ', Best_pos.pace) + newline;
                    
                    tocs(turn) = toc;

                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                    app.TextArea.Value = resultInString;
                elseif app.AlgorithmsDropDown.Value == "MOANA"
                    MOANA(app.lb, app.ub, app.dim, app.fobj, app.TestFunctionsDropDown_3.Value)
                    
                    tocs(turn) = toc;

                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                    app.TextArea.Value = resultInString;
                elseif app.AlgorithmsDropDown.Value == "CDDO"
                    [output] = CCD(app.NumberofIterationsSpinner.Value, app.lb, app.ub, app.dim, app.fobj);
                    
                    resultInString = resultInString + "Output: " + newline;
                    resultInString = resultInString + sprintf('%d\n', output) + newline;
                    
                    tocs(turn) = toc;
                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                elseif app.AlgorithmsDropDown.Value == "DDO"
                    [Best_score, Best_pos] = DDO(app.fobj, app.NumberofAgentsSpinner.Value, app.NumberofAgentsSpinner.Value, app.ub, app.lb, app.dim);
                    app.targetHistory(1, turn) = Best_score;

                    resultInString = resultInString + "Best score: " + newline;
                    resultInString = resultInString + sprintf('%d\n', Best_score);
                    resultInString = resultInString + "Best position: " + newline;
                    resultInString = resultInString + sprintf('%d\n', Best_pos);
                    
                    tocs(turn) = toc;

                    resultInString = resultInString + sprintf('turn took: %.5fs\n', tocs(turn));
                    resultInString = resultInString + "====================================" + newline + newline;
                    app.TextArea.Value = resultInString;

                end
            end
            app.targetHistory
            resultInString = resultInString + sprintf('algorithm took: %.5fs\n', sum(tocs));
            resultInString = resultInString +  sprintf('mean : %d\n', mean(app.targetHistory));
            resultInString = resultInString +  sprintf('std : %d\n', std(app.targetHistory));
            
            app.TextArea.Value = resultInString;
            app.Button.Enable = 'on';
            app.Button.Icon = 'play.png';
            app.output = resultInString;
        end

        % Value changed function: TestFunctionsDropDown_2
        function TestFunctionsDropDown_2ValueChanged(app, event)
            value = app.TestFunctionsDropDown_2.Value;
            [app.lb,app.ub,app.dim,app.fobj]=get_test_functions(value);
            app.lb
            app.ub
            app.lower_bound.Text = string(app.lb);
            app.upper_bound.Text = string(app.ub);
            app.dimention.Text = string(app.dim);


        end

        % Callback function
        function TestFunctionsDropDown_3Opening(app, event)
            value = app.TestFunctionsDropDown_3.Value;
            [app.lb,app.ub,app.dim,app.fobj]=get_test_functions(value);
           
        end

        % Value changed function: TestFunctionsDropDown_3
        function TestFunctionsDropDown_3ValueChanged(app, event)
            value = app.TestFunctionsDropDown_3.Value;
            [app.lb,app.ub,app.dim,app.fobj]=get_test_functions(value);
            
        end

        % Button pushed function: SavetextoutputButton
        function SavetextoutputButtonPushed(app, event)
            
            [file,location] = uiputfile('*.txt');
            fileName = location + "/" + file;
            % fileName = app.AlgorithmsDropDown.Value + "_output.txt";
            fid=fopen(fileName,'w');
            fprintf(fid, app.output);
            fclose(fid);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.GridLayout = uigridlayout(app.OptionsPanel);
            app.NumberofAgentsLabel = uilabel(app.GridLayout);
            app.NumberofAgentsLabel.Text = 'Number of Agents:';
            app.NumberofAgentsSpinner = uispinner(app.GridLayout);
            app.NumberofAgentsSpinner.Limits = [2 Inf];
            app.NumberofAgentsSpinner.Value = 10;
            app.NumberofItterationsLabel = uilabel(app.GridLayout);
            app.NumberofItterationsLabel.Text = 'Number of Iterations:';
            app.NumberofIterationsSpinner = uispinner(app.GridLayout);
            app.NumberofIterationsSpinner.Limits = [1 Inf];
            app.NumberofIterationsSpinner.Value = 100;
            app.LowerboundLabel = uilabel(app.GridLayout);
            app.LowerboundLabel.Text = 'Lower bound: ';
            app.lower_bound = uilabel(app.GridLayout);
            app.lower_bound.Text = '0';
            app.DimentionsLabel = uilabel(app.GridLayout);
            app.DimentionsLabel.Text = 'Dimentions';
            app.dimention = uilabel(app.GridLayout);
            app.dimention.Text = '0';
            app.AlgorithmsDropDownLabel = uilabel(app.GridLayout);
            app.AlgorithmsDropDownLabel.Text = 'Algorithms';
            app.AlgorithmsDropDown = uidropdown(app.GridLayout);
            app.AlgorithmsDropDown.Items = {'DDO', 'FOX', 'FDO', 'CFDO', 'CDDO', 'DCSO', 'MOANA'};
            app.AlgorithmsDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmsDropDownValueChanged, true);
            app.AlgorithmsDropDown.Value = 'DCSO';
            app.UpperboundLabel_2 = uilabel(app.GridLayout);
            app.UpperboundLabel_2.Text = 'Upper bound: ';
            app.upper_bound = uilabel(app.GridLayout);
            app.upper_bound.Text = '0';
            app.TurnsSpinnerLabel = uilabel(app.GridLayout);
            app.TurnsSpinnerLabel.Text = 'Turns';
            app.TurnsSpinner = uispinner(app.GridLayout);
            app.TurnsSpinner.Limits = [1 Inf];
            app.TurnsSpinner.Value = 1;
            app.OperationsPanel = uipanel(app.UIFigure);
            app.OperationsPanel.Title = 'Operations';
            app.GridLayout2 = uigridlayout(app.OperationsPanel);
            app.TestFunctionsDropDown_2Label = uilabel(app.GridLayout2);
            app.TestFunctionsDropDown_2Label.Text = 'Test Functions';
            app.TestFunctionsDropDown_2 = uidropdown(app.GridLayout2);
            app.TestFunctionsDropDown_2.Items = {'F1', 'F2', 'F3', 'F4', 'F5', 'F6', 'F7', 'F8', 'F9', 'F10', 'F11', 'F12', 'F13', 'F14', 'F15', 'F16', 'F17', 'F18', 'F19', 'F20', 'F21', 'F22', 'F23', 'CEC01', 'CEC02', 'CEC03', 'CEC04', 'CEC05', 'CEC06', 'CEC07', 'CEC08', 'CEC09', 'CEC10'};
            app.TestFunctionsDropDown_2.ItemsData = {'F1', 'F2', 'F3', 'F4', 'F5', 'F6', 'F7', 'F8', 'F9', 'F10', 'F11', 'F12', 'F13', 'F14', 'F15', 'F16', 'F17', 'F18', 'F19', 'F20', 'F21', 'F22', 'F23', 'cec01', 'cec02', 'cec03', 'cec04', 'cec05', 'cec06', 'cec07', 'cec08', 'cec09', 'cec10'};
            app.TestFunctionsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TestFunctionsDropDown_2ValueChanged, true);
            app.TestFunctionsDropDown_2.Value = 'F1';
            app.SavetextoutputButton = uibutton(app.GridLayout2, 'push');
            app.SavetextoutputButton.ButtonPushedFcn = createCallbackFcn(app, @SavetextoutputButtonPushed, true);
            app.SavetextoutputButton.Text = 'Save text output';
            app.Button = uibutton(app.GridLayout2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'play.png');
            app.Button.Text = '';
            app.WeightFactorSpinnerLabel = uilabel(app.GridLayout2);
            app.WeightFactorSpinnerLabel.Text = 'Weight Factor';
            app.WeightFactorSpinner = uispinner(app.GridLayout2);
            app.Spinner2Label = uilabel(app.GridLayout2);
            app.Spinner2Label.Text = 'Spinner2';
            app.Spinner2 = uispinner(app.GridLayout2);
            app.Spinner3Label = uilabel(app.GridLayout2);
            app.Spinner3Label.Text = 'Spinner3';
            app.Spinner3 = uispinner(app.GridLayout2);
            app.TestFunctionsDropDown_2Label_2 = uilabel(app.GridLayout2);
            app.TestFunctionsDropDown_2Label_2.Text = 'Test Functions';
            app.TestFunctionsDropDown_3 = uidropdown(app.GridLayout2);
            app.TestFunctionsDropDown_3.Items = {'ZDT1', 'ZDT2', 'ZDT4', 'Beam (real app.)'};
            app.TestFunctionsDropDown_3.ItemsData = {'ZDT1', 'ZDT2', 'ZDT4', 'beam'};
            app.TestFunctionsDropDown_3.ValueChangedFcn = createCallbackFcn(app, @TestFunctionsDropDown_3ValueChanged, true);
            app.TestFunctionsDropDown_3.Value = 'ZDT1';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end